classdef MyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatrixAppUIFigure        matlab.ui.Figure
        MatrixCalculatorPanel    matlab.ui.container.Panel
        PowerPanel               matlab.ui.container.Panel
        CalculateButton          matlab.ui.control.StateButton
        PowerN                   matlab.ui.control.NumericEditField
        RaisedtothepowerofEditFieldLabel  matlab.ui.control.Label
        ResetButton              matlab.ui.control.StateButton
        SwitchTotheNewAPPButton  matlab.ui.control.Button
        InverseButton            matlab.ui.control.Button
        PowerButton              matlab.ui.control.Button
        OutputPanel              matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        sq2Tab                   matlab.ui.container.Tab
        OutputPanel2x2           matlab.ui.container.Panel
        VA12_1                   matlab.ui.control.Button
        VA22_1                   matlab.ui.control.Button
        VA21_1                   matlab.ui.control.Button
        VA11_1                   matlab.ui.control.Button
        sq3Tab                   matlab.ui.container.Tab
        OutputPanel3x3           matlab.ui.container.Panel
        VA11                     matlab.ui.control.Button
        VA12                     matlab.ui.control.Button
        VA13                     matlab.ui.control.Button
        VA21                     matlab.ui.control.Button
        VA22                     matlab.ui.control.Button
        VA23                     matlab.ui.control.Button
        VA31                     matlab.ui.control.Button
        VA32                     matlab.ui.control.Button
        VA33                     matlab.ui.control.Button
        col3Tab                  matlab.ui.container.Tab
        OutputPanel1x3           matlab.ui.container.Panel
        Button_12                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        col2Tab                  matlab.ui.container.Tab
        OutputPanel1x2           matlab.ui.container.Panel
        VA                       matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        singleTab                matlab.ui.container.Tab
        OutputPanel1x1           matlab.ui.container.Panel
        EditField_10             matlab.ui.control.NumericEditField
        TransposeButton          matlab.ui.control.Button
        SelectRowsandColumnsoftheMatrixLabel  matlab.ui.control.Label
        OKButton                 matlab.ui.control.Button
        ColumnDropDown           matlab.ui.control.DropDown
        ColumnDropDownLabel      matlab.ui.control.Label
        RowDropDown              matlab.ui.control.DropDown
        RowDropDownLabel         matlab.ui.control.Label
        RankButton               matlab.ui.control.Button
        EigenValuesButton        matlab.ui.control.Button
        EigenVectorsButton       matlab.ui.control.Button
        MATRIXLabel              matlab.ui.control.Label
        TraceButton              matlab.ui.control.Button
        DeterminantButton        matlab.ui.control.Button
        A33                      matlab.ui.control.NumericEditField
        A32                      matlab.ui.control.NumericEditField
        A31                      matlab.ui.control.NumericEditField
        A23                      matlab.ui.control.NumericEditField
        A22                      matlab.ui.control.NumericEditField
        A21                      matlab.ui.control.NumericEditField
        A13                      matlab.ui.control.NumericEditField
        A12                      matlab.ui.control.NumericEditField
        A11                      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.OutputPanel3x3.Visible = 'off';
            % app.OutputPanel1x3.Visible = 'off';
            % app.OutputPanel1x1.Visible = 'off';
            app.A11.Enable  ="off";
            app.A12.Enable  ="off";
            app.A13.Enable  ="off";
            app.A21.Enable  ="off";
            app.A22.Enable  ="off";
            app.A23.Enable  ="off";
            app.A31.Enable  ="off";
            app.A32.Enable  ="off";
            app.A33.Enable  ="off";

            %Resetting the starting values
            app.A11.Value = 0;
            app.A12.Value = 0;
            app.A13.Value = 0;
            app.A21.Value = 0;
            app.A22.Value = 0;
            app.A23.Value = 0;
            app.A31.Value = 0;
            app.A32.Value = 0;
            app.A33.Value = 0;

            %making the extras invisible
            app.DeterminantButton.Visible = "off";
            app.TraceButton.Visible = "off";
            app.EigenVectorsButton.Visible= "off";
            app.RankButton.Visible= "off";
            app.EigenValuesButton.Visible = "off";
            app.TransposeButton.Visible = "off";
            app.PowerButton.Visible = "off";
            app.InverseButton.Visible = "off";
            app.PowerPanel.Visible = "off";


            %Turining the tabs off
            app.TabGroup.Visible = "off";
            app.OutputPanel.Visible = "off";
            
            %Turning the 
          
        end

        % Button pushed function: DeterminantButton
        function DeterminantButtonPushed(app, event)
            
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];

            elseif app.RowDropDown.Value == '3'

                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
            end
            app.TabGroup.SelectedTab = app.singleTab;
            D = det(A);
            app.EditField_10.Value = D;
            app.OutputPanel1x1.Visible = 'on';
            app.EditField_10.Visible = "on";
            app.OutputPanel.Title = "Determinant";
            app.OutputPanel3x3.Visible = 'off';
            app.OutputPanel1x3.Visible = 'off';
            app.TabGroup.Visible = "on";
            app.OutputPanel.Visible = "on";

        end

        % Button pushed function: TraceButton
        function TraceButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];

            elseif app.RowDropDown.Value == '3'
                
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
            end
            app.TabGroup.SelectedTab = app.singleTab;
            app.EditField_10.Value = trace(A);
            app.OutputPanel1x1.Visible = 'on';
            app.EditField_10.Visible = "on";
            app.OutputPanel.Title = "Trace";
            app.OutputPanel3x3.Visible = 'off';
            app.OutputPanel1x3.Visible = 'off';
            app.TabGroup.Visible = "on";
            app.OutputPanel.Visible = "on";

        end

        % Button pushed function: RankButton
        function RankButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];

            elseif app.RowDropDown.Value == '3'

                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
            end
            app.TabGroup.SelectedTab = app.singleTab;
            app.EditField_10.Value = rank(A);
            app.OutputPanel3x3.Visible = 'off';
            app.OutputPanel1x3.Visible = 'off';
            app.OutputPanel1x1.Visible = "on";
            app.OutputPanel.Title= "Rank";
            app.TabGroup.Visible = "on";
            app.OutputPanel.Visible = "on";

        end

        % Button pushed function: EigenValuesButton
        function EigenValuesButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];
                eigVal = eig(A);
                app.Button_10.Text = num2str(eigVal(1));
                app.Button_11.Text = num2str(eigVal(2));
                app.TabGroup.SelectedTab = app.col2Tab;

            elseif app.RowDropDown.Value == '3'

                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
                eigVal = eig(A);
                app.Button_10.Text = num2str(eigVal(1));
                app.Button_11.Text = num2str(eigVal(2));
                app.Button_12.Text = num2str(eigVal(3));
                app.TabGroup.SelectedTab = app.col3Tab;
            end
        
            app.OutputPanel.Title = "Eigen values";
            app.OutputPanel1x3.Visible = 'on';
            app.OutputPanel3x3.Visible = 'off';
            app.OutputPanel1x1.Visible = "off";
            app.TabGroup.Visible = "on";
            app.OutputPanel.Visible = "on";

        end

        % Button down function: OutputPanel1x3
        function OutputPanel1x3ButtonDown(app, event)
            
        end

        % Button pushed function: EigenVectorsButton
        function EigenVectorsButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];
                [eigVec, ~] = eig(A);
                app.VA11_1.Text = num2str(eigVec(1,1));
                app.VA12_1.Text = num2str(eigVec(1,2));
                app.VA21_1.Text = num2str(eigVec(2,1));
                app.VA22_1.Text = num2str(eigVec(2,2));
                app.TabGroup.SelectedTab = app.sq2Tab;


            elseif app.RowDropDown.Value == '3'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
                [eigVec, ~] = eig(A);

                app.VA11.Text = num2str(eigVec(1,1));
                app.VA12.Text = num2str(eigVec(1,2));
                app.VA13.Text = num2str(eigVec(1,3));
                app.VA21.Text = num2str(eigVec(2,1));
                app.VA22.Text = num2str(eigVec(2,2));
                app.VA23.Text = num2str(eigVec(2,3));
                app.VA31.Text = num2str(eigVec(3,1));
                app.VA32.Text = num2str(eigVec(3,2));
                app.VA33.Text = num2str(eigVec(3,3));
                app.TabGroup.SelectedTab = app.sq3Tab;
            end


            app.OutputPanel.Title = "Eigen Vectors";
            app.OutputPanel3x3.Visible = 'on';
            app.OutputPanel1x1.Visible = 'off';
            app.OutputPanel1x3.Visible = 'off';
            app.TabGroup.Visible = "on";
            app.OutputPanel.Visible = "on";

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.ColumnDropDown.Value  == '2' && app.RowDropDown.Value == '2'
                startupFcn(app)
                app.A11.Enable = "on";
                app.A12.Enable="on";
                app.A21.Enable= "on";
                app.A22.Enable= "on";


            elseif app.ColumnDropDown.Value  =='3' && app.RowDropDown.Value == '3'
                startupFcn(app)
                app.A11.Enable  ="on";
                app.A12.Enable  ="on";
                app.A13.Enable  ="on";
                app.A21.Enable  ="on";
                app.A22.Enable  ="on";
                app.A23.Enable  ="on";
                app.A31.Enable  ="on";
                app.A32.Enable  ="on";
                app.A33.Enable  ="on";

            else
                msgbox("Please select equal rows and columns!")
                startupFcn(app)
            end
            % Turning the buttons on
            app.DeterminantButton.Visible = "on";
            app.TraceButton.Visible = "on";
            app.EigenVectorsButton.Visible= "on";
            app.RankButton.Visible= "on";
            app.EigenValuesButton.Visible= "on";
            app.TransposeButton.Visible= "on";
            app.PowerButton.Visible= "on";
            app.InverseButton.Visible= "on";

        end

        % Button pushed function: TransposeButton
        function TransposeButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];
                M = A';

                %Putting the output
                app.VA11_1.Text = num2str(M(1,1));
                app.VA12_1.Text = num2str(M(1,2));
                app.VA21_1.Text = num2str(M(2,1));
                app.VA22_1.Text = num2str(M(2,2));

                %Switching the Tabs
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq2Tab;


            elseif app.RowDropDown.Value == '3'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
                M = A';

                %Putting the output
                app.VA11.Text = num2str(M(1,1));
                app.VA12.Text = num2str(M(1,2));
                app.VA13.Text = num2str(M(1,3));
                app.VA21.Text = num2str(M(2,1));
                app.VA22.Text = num2str(M(2,2));
                app.VA23.Text = num2str(M(2,3));
                app.VA31.Text = num2str(M(3,1));
                app.VA32.Text = num2str(M(3,2));
                app.VA33.Text = num2str(M(3,3));

                %Switching the Tabs
                app.OutputPanel.Visible = "on";
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq3Tab;

            end

            app.OutputPanel.Title =  "Transpose";

        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            startupFcn(app);
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            app.PowerPanel.Visible = "on";

        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            n = app.PowerN.Value;
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];
                M = A^n;

                %Putting the Output
                app.VA11_1.Text = num2str(M(1,1));
                app.VA12_1.Text = num2str(M(1,2));
                app.VA21_1.Text = num2str(M(2,1));
                app.VA22_1.Text = num2str(M(2,2));

                %Switching the Tabs
                app.OutputPanel.Visible = "on";
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq2Tab;


            elseif app.RowDropDown.Value == '3'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
                M = A^n;


                %Putting the Output
                app.VA11.Text = num2str(M(1,1));
                app.VA12.Text = num2str(M(1,2));
                app.VA13.Text = num2str(M(1,3));
                app.VA21.Text = num2str(M(2,1));
                app.VA22.Text = num2str(M(2,2));
                app.VA23.Text = num2str(M(2,3));
                app.VA31.Text = num2str(M(3,1));
                app.VA32.Text = num2str(M(3,2));
                app.VA33.Text = num2str(M(3,3));

                %Switching the Tabs
                app.OutputPanel.Visible = "on";
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq3Tab;

            end

            app.OutputPanel.Title =  "Power raised to n";
            
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            if app.RowDropDown.Value == '2'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                A = [a11 a12 ; a21 a22];
                M = inv(A);

                %Putting the Output
                app.VA11_1.Text = num2str(M(1,1));
                app.VA12_1.Text = num2str(M(1,2));
                app.VA21_1.Text = num2str(M(2,1));
                app.VA22_1.Text = num2str(M(2,2));

                %Switching the Tabs
                app.OutputPanel.Visible = "on";
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq2Tab;


            elseif app.RowDropDown.Value == '3'
                a11 = app.A11.Value;
                a12 = app.A12.Value;
                a13 = app.A13.Value;
                a21 = app.A21.Value;
                a22 = app.A22.Value;
                a23 = app.A23.Value;
                a31 = app.A31.Value;
                a32 = app.A32.Value;
                a33 = app.A33.Value;
                A = [a11 a12 a13 ;a21 a22 a23 ; a31 a32 a33];
                M = inv(A);


                %Putting the Output
                app.VA11.Text = num2str(M(1,1));
                app.VA12.Text = num2str(M(1,2));
                app.VA13.Text = num2str(M(1,3));
                app.VA21.Text = num2str(M(2,1));
                app.VA22.Text = num2str(M(2,2));
                app.VA23.Text = num2str(M(2,3));
                app.VA31.Text = num2str(M(3,1));
                app.VA32.Text = num2str(M(3,2));
                app.VA33.Text = num2str(M(3,3));

                %Switching the Tabs
                app.OutputPanel.Visible = "on";
                app.TabGroup.Visible = "on";
                app.TabGroup.SelectedTab = app.sq3Tab;

            end
            app.OutputPanel.Title =  "Inverse of Your Matrix";
        end

        % Button pushed function: SwitchTotheNewAPPButton
        function SwitchTotheNewAPPButtonPushed(app, event)
            app2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatrixAppUIFigure = uifigure('Visible', 'off');
            app.MatrixAppUIFigure.Name = 'Matrix App';
            app.MatrixCalculatorPanel = uipanel(app.MatrixAppUIFigure);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.A11 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A12 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A13 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A21 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A22 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A23 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A31 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A32 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A33 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.DeterminantButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.DeterminantButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminantButtonPushed, true);
            app.DeterminantButton.Text = 'Determinant';
            app.TraceButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.TraceButton.ButtonPushedFcn = createCallbackFcn(app, @TraceButtonPushed, true);
            app.TraceButton.Text = 'Trace';
            app.MATRIXLabel = uilabel(app.MatrixCalculatorPanel);
            app.MATRIXLabel.Text = 'MATRIX';
            app.EigenVectorsButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.EigenVectorsButton.ButtonPushedFcn = createCallbackFcn(app, @EigenVectorsButtonPushed, true);
            app.EigenVectorsButton.Text = 'Eigen Vectors';
            app.EigenValuesButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.EigenValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenValuesButtonPushed, true);
            app.EigenValuesButton.Text = 'Eigen Values';
            app.RankButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.RankButton.ButtonPushedFcn = createCallbackFcn(app, @RankButtonPushed, true);
            app.RankButton.Text = 'Rank';
            app.RowDropDownLabel = uilabel(app.MatrixCalculatorPanel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.MatrixCalculatorPanel);
            app.RowDropDown.Items = {'2', '3'};
            app.RowDropDown.Value = '2';
            app.ColumnDropDownLabel = uilabel(app.MatrixCalculatorPanel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.MatrixCalculatorPanel);
            app.ColumnDropDown.Items = {'2', '3'};
            app.ColumnDropDown.Value = '2';
            app.OKButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SelectRowsandColumnsoftheMatrixLabel = uilabel(app.MatrixCalculatorPanel);
            app.SelectRowsandColumnsoftheMatrixLabel.Text = 'Select Rows and Columns of the Matrix';
            app.TransposeButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.TransposeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeButtonPushed, true);
            app.TransposeButton.Text = 'Transpose';
            app.TabGroup = uitabgroup(app.MatrixCalculatorPanel);
            app.sq2Tab = uitab(app.TabGroup);
            app.sq2Tab.Title = '2x2';
            app.OutputPanel2x2 = uipanel(app.sq2Tab);
            app.VA11_1 = uibutton(app.OutputPanel2x2, 'push');
            app.VA11_1.Text = '';
            app.VA21_1 = uibutton(app.OutputPanel2x2, 'push');
            app.VA21_1.Text = '';
            app.VA22_1 = uibutton(app.OutputPanel2x2, 'push');
            app.VA22_1.Text = '';
            app.VA12_1 = uibutton(app.OutputPanel2x2, 'push');
            app.VA12_1.Text = '';
            app.sq3Tab = uitab(app.TabGroup);
            app.sq3Tab.Title = '3x3';
            app.OutputPanel3x3 = uipanel(app.sq3Tab);
            app.VA33 = uibutton(app.OutputPanel3x3, 'push');
            app.VA33.Text = '';
            app.VA32 = uibutton(app.OutputPanel3x3, 'push');
            app.VA32.Text = '';
            app.VA31 = uibutton(app.OutputPanel3x3, 'push');
            app.VA31.Text = '';
            app.VA23 = uibutton(app.OutputPanel3x3, 'push');
            app.VA23.Text = '';
            app.VA22 = uibutton(app.OutputPanel3x3, 'push');
            app.VA22.Text = '';
            app.VA21 = uibutton(app.OutputPanel3x3, 'push');
            app.VA21.Text = '';
            app.VA13 = uibutton(app.OutputPanel3x3, 'push');
            app.VA13.Text = '';
            app.VA12 = uibutton(app.OutputPanel3x3, 'push');
            app.VA12.Text = '';
            app.VA11 = uibutton(app.OutputPanel3x3, 'push');
            app.VA11.Text = '';
            app.col3Tab = uitab(app.TabGroup);
            app.col3Tab.Title = '1x3';
            app.OutputPanel1x3 = uipanel(app.col3Tab);
            app.OutputPanel1x3.ButtonDownFcn = createCallbackFcn(app, @OutputPanel1x3ButtonDown, true);
            app.Button_10 = uibutton(app.OutputPanel1x3, 'push');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.OutputPanel1x3, 'push');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.OutputPanel1x3, 'push');
            app.Button_12.Text = '';
            app.col2Tab = uitab(app.TabGroup);
            app.col2Tab.Title = '1x2';
            app.OutputPanel1x2 = uipanel(app.col2Tab);
            app.Button_13 = uibutton(app.OutputPanel1x2, 'push');
            app.Button_13.Text = '';
            app.VA = uibutton(app.OutputPanel1x2, 'push');
            app.VA.Text = '';
            app.singleTab = uitab(app.TabGroup);
            app.singleTab.Title = '1x1';
            app.OutputPanel1x1 = uipanel(app.singleTab);
            app.EditField_10 = uieditfield(app.OutputPanel1x1, 'numeric');
            app.OutputPanel = uipanel(app.MatrixCalculatorPanel);
            app.OutputPanel.Title = 'Output';
            app.PowerButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.InverseButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.SwitchTotheNewAPPButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.SwitchTotheNewAPPButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchTotheNewAPPButtonPushed, true);
            app.SwitchTotheNewAPPButton.Text = 'Switch To the New APP';
            app.ResetButton = uibutton(app.MatrixCalculatorPanel, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.PowerPanel = uipanel(app.MatrixCalculatorPanel);
            app.RaisedtothepowerofEditFieldLabel = uilabel(app.PowerPanel);
            app.RaisedtothepowerofEditFieldLabel.Text = 'Raised to the power of';
            app.PowerN = uieditfield(app.PowerPanel, 'numeric');
            app.CalculateButton = uibutton(app.PowerPanel, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.MatrixAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatrixAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatrixAppUIFigure)
        end
    end
end