classdef MyAppFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OtherAppsMenu          matlab.ui.container.Menu
        DualMatrixMenu         matlab.ui.container.Menu
        LinearEqCalMenu        matlab.ui.container.Menu
        MatrixCalculatorPanel  matlab.ui.container.Panel
        ResetButton            matlab.ui.control.StateButton
        OutputLabel            matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        sq3Tab                 matlab.ui.container.Tab
        OutputPanel3x3         matlab.ui.container.Panel
        VA11                   matlab.ui.control.Button
        VA12                   matlab.ui.control.Button
        VA13                   matlab.ui.control.Button
        VA21                   matlab.ui.control.Button
        VA22                   matlab.ui.control.Button
        VA23                   matlab.ui.control.Button
        VA31                   matlab.ui.control.Button
        VA32                   matlab.ui.control.Button
        VA33                   matlab.ui.control.Button
        sq2Tab                 matlab.ui.container.Tab
        OutputPanel2x2         matlab.ui.container.Panel
        VA11_2x2               matlab.ui.control.Button
        VA12_2x2               matlab.ui.control.Button
        VA21_2x2               matlab.ui.control.Button
        VA22_2x2               matlab.ui.control.Button
        col3Tab                matlab.ui.container.Tab
        OutputPanel1x3         matlab.ui.container.Panel
        VA1_1X3                matlab.ui.control.Button
        VA2_1X3                matlab.ui.control.Button
        VA3_1X3                matlab.ui.control.Button
        col2Tab                matlab.ui.container.Tab
        OutputPanel1x2         matlab.ui.container.Panel
        VA1_1X2                matlab.ui.control.Button
        VA2_1X2                matlab.ui.control.Button
        singleTab              matlab.ui.container.Tab
        OutputPanel1x1         matlab.ui.container.Panel
        EditField              matlab.ui.control.NumericEditField
        PowerPanel             matlab.ui.container.Panel
        CalculateButton        matlab.ui.control.StateButton
        PowerN                 matlab.ui.control.NumericEditField
        RaisedtothepowerofEditFieldLabel  matlab.ui.control.Label
        PowerButton            matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        DeterminantButton      matlab.ui.control.Button
        TraceButton            matlab.ui.control.Button
        RankButton             matlab.ui.control.Button
        EigenVectorsButton     matlab.ui.control.Button
        EigenValuesButton      matlab.ui.control.Button
        TransposeButton        matlab.ui.control.Button
        InverseButton          matlab.ui.control.Button
        RowsAndColumnsLabel    matlab.ui.control.Label
        ColumnDropDown         matlab.ui.control.DropDown
        ColumnDropDownLabel    matlab.ui.control.Label
        RowDropDown            matlab.ui.control.DropDown
        RowDropDownLabel       matlab.ui.control.Label
        MATRIXLabel            matlab.ui.control.Label
        A11                    matlab.ui.control.NumericEditField
        A12                    matlab.ui.control.NumericEditField
        A13                    matlab.ui.control.NumericEditField
        A21                    matlab.ui.control.NumericEditField
        A22                    matlab.ui.control.NumericEditField
        A23                    matlab.ui.control.NumericEditField
        A31                    matlab.ui.control.NumericEditField
        A33                    matlab.ui.control.NumericEditField
        A32                    matlab.ui.control.NumericEditField
        xButton                matlab.ui.control.Button
    end


    methods (Access = public)

        %Function to get the matrix in any scenario   :)
        function A = GetMatrix(app)
            if app.RowDropDown.Value == '2'
                A = [app.A11.Value app.A12.Value ;
                    app.A21.Value app.A22.Value] ;
            elseif app.RowDropDown.Value == '3'
                A = [app.A11.Value app.A12.Value app.A13.Value ;
                    app.A21.Value app.A22.Value app.A23.Value ;
                    app.A31.Value app.A22.Value app.A33.Value] ;
            else
                A = [];

            end

        end
    end

    methods (Access = private)

        function FormattedValue = formatValue(~,A)
            if mod(A,1) == 0

                FormattedValue = num2str(A);

            else
                FormattedValue = num2str(A,'%.2f');

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.A11.Enable  ="off";
            app.A12.Enable  ="off";
            app.A13.Enable  ="off";
            app.A21.Enable  ="off";
            app.A22.Enable  ="off";
            app.A23.Enable  ="off";
            app.A31.Enable  ="off";
            app.A32.Enable  ="off";
            app.A33.Enable  ="off";

            %Resetting the starting values
            app.A11.Value = 0;
            app.A12.Value = 0;
            app.A13.Value = 0;
            app.A21.Value = 0;
            app.A22.Value = 0;
            app.A23.Value = 0;
            app.A31.Value = 0;
            app.A32.Value = 0;
            app.A33.Value = 0;

            %making the extras invisible
            app.DeterminantButton.Visible = "off";
            app.TraceButton.Visible = "off";
            app.EigenVectorsButton.Visible= "off";
            app.RankButton.Visible= "off";
            app.EigenValuesButton.Visible = "off";
            app.TransposeButton.Visible = "off";
            app.PowerButton.Visible = "off";
            app.InverseButton.Visible = "off";
            app.PowerPanel.Visible = "off";
            app.xButton.Visible = "off";


            %Turining the tabs off
            app.TabGroup.Visible = "off";
            app.OutputLabel.Visible = "off";

            %Turning the

        end

        % Button pushed function: DeterminantButton
        function DeterminantButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Acutal Function
            D = det(A);
            app.EditField.Value = D;

            % Configuring the Tab
            app.OutputLabel.Text = "Determinant";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";
            app.TabGroup.SelectedTab = app.singleTab;
            
        end

        % Button pushed function: TraceButton
        function TraceButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Acutal Function
            D = trace(A);
            app.EditField.Value = D;

            % Configuring the Tab
            app.OutputLabel.Text = "Trace";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";
            app.TabGroup.SelectedTab = app.singleTab;
        end

        % Button pushed function: RankButton
        function RankButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Acutal Function
            D = rank(A);
            app.EditField.Value = D;

            % Configuring the Tab
            app.OutputLabel.Text = "Rank";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";
            app.TabGroup.SelectedTab = app.singleTab;

        end

        % Button pushed function: EigenValuesButton
        function EigenValuesButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Actual Function
            eigVal = eig(A);

            if app.RowDropDown.Value == '2'
                %Putting the Output
                app.VA1_1X2.Text = num2str(eigVal(1));
                app.VA2_1X2.Text = num2str(eigVal(2));
                app.TabGroup.SelectedTab = app.col2Tab;

            elseif app.RowDropDown.Value == '3'
                %Putting the Output
                app.VA1_1X3.Text = num2str(eigVal(1));
                app.VA2_1X3.Text = num2str(eigVal(2));
                app.VA3_1X3.Text = num2str(eigVal(3));
                app.TabGroup.SelectedTab = app.col3Tab;

            end

            % Configuring the Tab
            app.OutputLabel.Text = "Eigen values";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";

        end

        % Button pushed function: EigenVectorsButton
        function EigenVectorsButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Actual Function
            [eigVec, ~] = eig(A);

            % Condition where the Answer is real

            if isreal(eigVec)
                if app.RowDropDown.Value == '2'
                    %Putting the Output
                    app.VA11.Text = num2str(eigVec(1));
                    app.VA12.Text = num2str(eigVec(2));

                    app.TabGroup.SelectedTab = app.col2Tab;

                elseif app.RowDropDown.Value == '3'
                    %Putting the Output
                    app.VA1_1X3.Text = num2str(eigVec(1));
                    app.VA2_1X3.Text = num2str(eigVec(2));
                    app.VA3_1X3.Text = num2str(eigVec(3));
                    app.TabGroup.SelectedTab = app.col3Tab;

                end
            end


                % Configuring the Tab
                app.OutputLabel.Text = "Eigen Vectors";
                app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.ColumnDropDown.Value  == '2' && app.RowDropDown.Value == '2'
                startupFcn(app)
                app.A11.Enable = "on";
                app.A12.Enable="on";
                app.A21.Enable= "on";
                app.A22.Enable= "on";


            elseif app.ColumnDropDown.Value  =='3' && app.RowDropDown.Value == '3'
                startupFcn(app)
                app.A11.Enable  ="on";
                app.A12.Enable  ="on";
                app.A13.Enable  ="on";
                app.A21.Enable  ="on";
                app.A22.Enable  ="on";
                app.A23.Enable  ="on";
                app.A31.Enable  ="on";
                app.A32.Enable  ="on";
                app.A33.Enable  ="on";

            else
                msgbox("Please select equal rows and columns!")
                startupFcn(app)
            end

            % Turning the buttons on
            app.DeterminantButton.Visible = "on";
            app.TraceButton.Visible = "on";
            app.EigenVectorsButton.Visible= "on";
            app.RankButton.Visible= "on";
            app.EigenValuesButton.Visible= "on";
            app.TransposeButton.Visible= "on";
            app.PowerButton.Visible= "on";
            app.InverseButton.Visible= "on";

        end

        % Button pushed function: TransposeButton
        function TransposeButtonPushed(app, event)
            % Calling the Public function that I defined earlier to get the matrix
            A = app.GetMatrix();

            % Actual Function
            T = A' ;

            if app.RowDropDown.Value == '2'
                %Putting the Output
                app.VA11_2x2.Text = num2str(T(1,1));
                app.VA12_2x2.Text = num2str(T(1,2));
                app.VA21_2x2.Text = num2str(T(2,1));
                app.VA22_2x2.Text = num2str(T(2,2));
                app.TabGroup.SelectedTab = app.sq2Tab;

            elseif app.RowDropDown.Value == '3'
                %Putting the Output
                app.VA11.Text = num2str(T(1,1));
                app.VA12.Text = num2str(T(1,2));
                app.VA13.Text = num2str(T(1,3));
                app.VA21.Text = num2str(T(2,1));
                app.VA22.Text = num2str(T(2,2));
                app.VA23.Text = num2str(T(2,3));
                app.VA31.Text = num2str(T(3,1));
                app.VA32.Text = num2str(T(3,2));
                app.VA33.Text = num2str(T(3,3));
                app.TabGroup.SelectedTab = app.sq3Tab;
            end

            % Configuring the Tab
            app.OutputLabel.Text = "Transpose";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            startupFcn(app);
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            app.PowerPanel.Visible = "on";

        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            n = app.PowerN.Value;
            A = app.GetMatrix();

            %Actual code
            M = A^n;
            if app.RowDropDown.Value == '2'
                
                %Putting the Output
                app.VA11_2x2.Text = num2str(M(1,1));
                app.VA12_2x2.Text = num2str(M(1,2));
                app.VA21_2x2.Text = num2str(M(2,1));
                app.VA22_2x2.Text = num2str(M(2,2));
                app.TabGroup.SelectedTab = app.sq2Tab;

            elseif app.RowDropDown.Value == '3'
                
                %Putting the Output
                app.VA11.Text = num2str(M(1,1));
                app.VA12.Text = num2str(M(1,2));
                app.VA13.Text = num2str(M(1,3));
                app.VA21.Text = num2str(M(2,1));
                app.VA22.Text = num2str(M(2,2));
                app.VA23.Text = num2str(M(2,3));
                app.VA31.Text = num2str(M(3,1));
                app.VA32.Text = num2str(M(3,2));
                app.VA33.Text = num2str(M(3,3));
                app.TabGroup.SelectedTab = app.sq3Tab;

            end

            % Configuring the Tab
            app.OutputLabel.Text =  "Power raised to n";
            app.TabGroup.Visible = "on";
            app.OutputLabel.Visible = "on";

        end

        % Menu selected function: DualMatrixMenu
        function DualMatrixMenuSelected(app, event)
            %Launching the DualMatrix app
            DualMatrix 
            %Minimizing the state of the main app
            app.UIFigure.WindowState = 'minimized';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OtherAppsMenu = uimenu(app.UIFigure);
            app.OtherAppsMenu.Text = 'Other Apps';
            app.DualMatrixMenu = uimenu(app.OtherAppsMenu);
            app.DualMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @DualMatrixMenuSelected, true);
            app.DualMatrixMenu.Text = 'DualMatrix';
            app.LinearEqCalMenu = uimenu(app.OtherAppsMenu);
            app.LinearEqCalMenu.Text = 'LinearEq Cal';
            app.MatrixCalculatorPanel = uipanel(app.UIFigure);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.xButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.xButton.Text = 'x';
            app.A32 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A33 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A31 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A23 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A22 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A21 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A13 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A12 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.A11 = uieditfield(app.MatrixCalculatorPanel, 'numeric');
            app.MATRIXLabel = uilabel(app.MatrixCalculatorPanel);
            app.MATRIXLabel.Text = 'MATRIX';
            app.RowDropDownLabel = uilabel(app.MatrixCalculatorPanel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.MatrixCalculatorPanel);
            app.RowDropDown.Items = {'2', '3'};
            app.RowDropDown.Value = '2';
            app.ColumnDropDownLabel = uilabel(app.MatrixCalculatorPanel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.MatrixCalculatorPanel);
            app.ColumnDropDown.Items = {'2', '3'};
            app.ColumnDropDown.Value = '2';
            app.RowsAndColumnsLabel = uilabel(app.MatrixCalculatorPanel);
            app.RowsAndColumnsLabel.Text = 'Select Rows and Columns of the Matrix';
            app.InverseButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.InverseButton.Text = 'Inverse';
            app.TransposeButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.TransposeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeButtonPushed, true);
            app.TransposeButton.Text = 'Transpose';
            app.EigenValuesButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.EigenValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenValuesButtonPushed, true);
            app.EigenValuesButton.Text = 'Eigen Values';
            app.EigenVectorsButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.EigenVectorsButton.ButtonPushedFcn = createCallbackFcn(app, @EigenVectorsButtonPushed, true);
            app.EigenVectorsButton.Text = 'Eigen Vectors';
            app.RankButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.RankButton.ButtonPushedFcn = createCallbackFcn(app, @RankButtonPushed, true);
            app.RankButton.Text = 'Rank';
            app.TraceButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.TraceButton.ButtonPushedFcn = createCallbackFcn(app, @TraceButtonPushed, true);
            app.TraceButton.Text = 'Trace';
            app.DeterminantButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.DeterminantButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminantButtonPushed, true);
            app.DeterminantButton.Text = 'Determinant';
            app.OKButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PowerButton = uibutton(app.MatrixCalculatorPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.PowerPanel = uipanel(app.MatrixCalculatorPanel);
            app.RaisedtothepowerofEditFieldLabel = uilabel(app.PowerPanel);
            app.RaisedtothepowerofEditFieldLabel.Text = 'Raised to the power of';
            app.PowerN = uieditfield(app.PowerPanel, 'numeric');
            app.CalculateButton = uibutton(app.PowerPanel, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.MatrixCalculatorPanel);
            app.sq3Tab = uitab(app.TabGroup);
            app.sq3Tab.Title = '3x3';
            app.OutputPanel3x3 = uipanel(app.sq3Tab);
            app.VA33 = uibutton(app.OutputPanel3x3, 'push');
            app.VA33.Text = '';
            app.VA32 = uibutton(app.OutputPanel3x3, 'push');
            app.VA32.Text = '';
            app.VA31 = uibutton(app.OutputPanel3x3, 'push');
            app.VA31.Text = '';
            app.VA23 = uibutton(app.OutputPanel3x3, 'push');
            app.VA23.Text = '';
            app.VA22 = uibutton(app.OutputPanel3x3, 'push');
            app.VA22.Text = '';
            app.VA21 = uibutton(app.OutputPanel3x3, 'push');
            app.VA21.Text = '';
            app.VA13 = uibutton(app.OutputPanel3x3, 'push');
            app.VA13.Text = '';
            app.VA12 = uibutton(app.OutputPanel3x3, 'push');
            app.VA12.Text = '';
            app.VA11 = uibutton(app.OutputPanel3x3, 'push');
            app.VA11.Text = '';
            app.sq2Tab = uitab(app.TabGroup);
            app.sq2Tab.Title = '2x2';
            app.OutputPanel2x2 = uipanel(app.sq2Tab);
            app.VA22_2x2 = uibutton(app.OutputPanel2x2, 'push');
            app.VA22_2x2.Text = '';
            app.VA21_2x2 = uibutton(app.OutputPanel2x2, 'push');
            app.VA21_2x2.Text = '';
            app.VA12_2x2 = uibutton(app.OutputPanel2x2, 'push');
            app.VA12_2x2.Text = '';
            app.VA11_2x2 = uibutton(app.OutputPanel2x2, 'push');
            app.VA11_2x2.Text = '';
            app.col3Tab = uitab(app.TabGroup);
            app.col3Tab.Title = '1x3';
            app.OutputPanel1x3 = uipanel(app.col3Tab);
            app.VA3_1X3 = uibutton(app.OutputPanel1x3, 'push');
            app.VA3_1X3.Text = '';
            app.VA2_1X3 = uibutton(app.OutputPanel1x3, 'push');
            app.VA2_1X3.Text = '';
            app.VA1_1X3 = uibutton(app.OutputPanel1x3, 'push');
            app.VA1_1X3.Text = '';
            app.col2Tab = uitab(app.TabGroup);
            app.col2Tab.Title = '1x2';
            app.OutputPanel1x2 = uipanel(app.col2Tab);
            app.VA2_1X2 = uibutton(app.OutputPanel1x2, 'push');
            app.VA2_1X2.Text = '';
            app.VA1_1X2 = uibutton(app.OutputPanel1x2, 'push');
            app.VA1_1X2.Text = '';
            app.singleTab = uitab(app.TabGroup);
            app.singleTab.Title = '1x1';
            app.OutputPanel1x1 = uipanel(app.singleTab);
            app.EditField = uieditfield(app.OutputPanel1x1, 'numeric');
            app.OutputLabel = uilabel(app.MatrixCalculatorPanel);
            app.OutputLabel.Text = 'Output';
            app.ResetButton = uibutton(app.MatrixCalculatorPanel, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.ResetButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyAppFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end