classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConfirmButton          matlab.ui.control.Button
        HowManyVariablesyourequationhaveDropDownLabel  matlab.ui.control.Label
        NumOfVariables         matlab.ui.control.DropDown
        TabGroup               matlab.ui.container.TabGroup
        EqTab2                 matlab.ui.container.Tab
        SolveButton            matlab.ui.control.Button
        ValuesPanel            matlab.ui.container.Panel
        ValueX                 matlab.ui.control.Button
        ValueY                 matlab.ui.control.Button
        Equation1Panel         matlab.ui.container.Panel
        x1                     matlab.ui.control.NumericEditField
        y1                     matlab.ui.control.NumericEditField
        c1                     matlab.ui.control.NumericEditField
        xEditField_11Label     matlab.ui.control.Label
        xEditField_10Label     matlab.ui.control.Label
        AddSign                matlab.ui.control.Label
        EqualSign              matlab.ui.control.Label
        Equation2Panel         matlab.ui.container.Panel
        x2                     matlab.ui.control.NumericEditField
        y2                     matlab.ui.control.NumericEditField
        c2                     matlab.ui.control.NumericEditField
        yEditField_15Label     matlab.ui.control.Label
        xEditField_8Label_2    matlab.ui.control.Label
        AddSign1               matlab.ui.control.Label
        EqualSign1             matlab.ui.control.Label
        EqTab3                 matlab.ui.container.Tab
        Equation2Panel_2       matlab.ui.container.Panel
        C2                     matlab.ui.control.NumericEditField
        xEditField_10Label_7   matlab.ui.control.Label
        Z2                     matlab.ui.control.NumericEditField
        xEditField_10Label_6   matlab.ui.control.Label
        Y2                     matlab.ui.control.NumericEditField
        xEditField_10Label_5   matlab.ui.control.Label
        X2                     matlab.ui.control.NumericEditField
        AddSignEq2_2           matlab.ui.control.Label
        EqualToEq2             matlab.ui.control.Label
        AddSignEq2             matlab.ui.control.Label
        Equation3Panel         matlab.ui.container.Panel
        C3                     matlab.ui.control.NumericEditField
        xEditField_10Label_10  matlab.ui.control.Label
        Z3                     matlab.ui.control.NumericEditField
        xEditField_10Label_9   matlab.ui.control.Label
        Y3                     matlab.ui.control.NumericEditField
        xEditField_10Label_8   matlab.ui.control.Label
        X3                     matlab.ui.control.NumericEditField
        AddSignEq3             matlab.ui.control.Label
        EqualToEq3             matlab.ui.control.Label
        AddSignEq3_2           matlab.ui.control.Label
        Equation1Panel_2       matlab.ui.container.Panel
        X1                     matlab.ui.control.NumericEditField
        AddSign2               matlab.ui.control.Label
        Y1                     matlab.ui.control.NumericEditField
        Z1                     matlab.ui.control.NumericEditField
        AddSign3               matlab.ui.control.Label
        EqualTo                matlab.ui.control.Label
        C1                     matlab.ui.control.NumericEditField
        xEditField_10Label_4   matlab.ui.control.Label
        xEditField_10Label_3   matlab.ui.control.Label
        xEditField_10Label_2   matlab.ui.control.Label
        Tab                    matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function A = coeffMatrix(app)
            if app.NumOfVariables.Value == "Two Variables"
                A = [app.x1.Value app.y1.Value ;
                     app.x2.Value app.y2.Value ] ;
            elseif app.NumOfVariables.Value == "Three Variables"
                A = [app.X1.Value app.Y1.Value app.Z1.Value ;
                     app.X2.Value app.Y2.Value app.Z2.Value ;
                     app.X3.Value app.Y3.Value app.Z3.Value ];
            end            
        end


        function C = constMatrix(app)
            if app.NumOfVariables.Value == "Two Variables"
                C = [app.c1.Value ;
                     app.c2.Value ];
            elseif app.NumOfVariables.Value == "Three Variables"
                C = [app.C1.Value ;
                     app.C2.Value ;
                     app.C3.Value ];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = 'off';
            app.ValuesPanel.Visible = 'off';
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)

            if app.NumOfVariables.Value == "Three Variables"
                app.TabGroup.SelectedTab = app.EqTab3;

            elseif app.NumOfVariables.Value == "Two Variables"
                app.TabGroup.SelectedTab = app.EqTab2;

            end
            app.TabGroup.Visible = 'on';
            

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            A = app.coeffMatrix();
            C = app.constMatrix();

            rA = rank(A); %rank of the coefficient matrix
            rAB = rank([A C]); %rank of the augmented matrix
            n = size(A,2); % size(A,1) = number of rows ||||  size(A,2) = number of columns

            if rA ~= rAB 
                msgbox("Error! This equation is not having any solutions at all");
            elseif rA == rAB && rA == n
                if n == 2
                X = A\C;
                app.ValueX.Text = num2str(X(1));
                app.ValueY.Text = num2str(X(2));
                app.ValuesPanel.Visible = 'on';

            else 
                msgbox("Chal kutte");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EqTab2 = uitab(app.TabGroup);
            app.EqTab2.Title = '2Eq';
            app.Equation2Panel = uipanel(app.EqTab2);
            app.Equation2Panel.Title = 'Equation 2';
            app.EqualSign1 = uilabel(app.Equation2Panel);
            app.EqualSign1.Text = '=';
            app.AddSign1 = uilabel(app.Equation2Panel);
            app.AddSign1.Text = '+';
            app.xEditField_8Label_2 = uilabel(app.Equation2Panel);
            app.xEditField_8Label_2.Text = 'x';
            app.yEditField_15Label = uilabel(app.Equation2Panel);
            app.yEditField_15Label.Text = 'y';
            app.c2 = uieditfield(app.Equation2Panel, 'numeric');
            app.y2 = uieditfield(app.Equation2Panel, 'numeric');
            app.x2 = uieditfield(app.Equation2Panel, 'numeric');
            app.Equation1Panel = uipanel(app.EqTab2);
            app.Equation1Panel.Title = 'Equation 1';
            app.EqualSign = uilabel(app.Equation1Panel);
            app.EqualSign.Text = '=';
            app.AddSign = uilabel(app.Equation1Panel);
            app.AddSign.Text = '+';
            app.xEditField_10Label = uilabel(app.Equation1Panel);
            app.xEditField_10Label.Text = 'x';
            app.xEditField_11Label = uilabel(app.Equation1Panel);
            app.xEditField_11Label.Text = 'x';
            app.c1 = uieditfield(app.Equation1Panel, 'numeric');
            app.y1 = uieditfield(app.Equation1Panel, 'numeric');
            app.x1 = uieditfield(app.Equation1Panel, 'numeric');
            app.ValuesPanel = uipanel(app.EqTab2);
            app.ValuesPanel.Title = 'Values';
            app.ValueY = uibutton(app.ValuesPanel, 'push');
            app.ValueY.Text = 'ValueY';
            app.ValueX = uibutton(app.ValuesPanel, 'push');
            app.ValueX.Text = 'ValueX';
            app.SolveButton = uibutton(app.EqTab2, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve The Equations';
            app.EqTab3 = uitab(app.TabGroup);
            app.EqTab3.Title = '3Eq';
            app.Equation1Panel_2 = uipanel(app.EqTab3);
            app.Equation1Panel_2.Title = 'Equation 1';
            app.xEditField_10Label_2 = uilabel(app.Equation1Panel_2);
            app.xEditField_10Label_2.Text = 'x';
            app.xEditField_10Label_3 = uilabel(app.Equation1Panel_2);
            app.xEditField_10Label_3.Text = 'y';
            app.xEditField_10Label_4 = uilabel(app.Equation1Panel_2);
            app.xEditField_10Label_4.Text = 'z';
            app.C1 = uieditfield(app.Equation1Panel_2, 'numeric');
            app.EqualTo = uilabel(app.Equation1Panel_2);
            app.EqualTo.Text = '=';
            app.AddSign3 = uilabel(app.Equation1Panel_2);
            app.AddSign3.Text = '+';
            app.Z1 = uieditfield(app.Equation1Panel_2, 'numeric');
            app.Y1 = uieditfield(app.Equation1Panel_2, 'numeric');
            app.AddSign2 = uilabel(app.Equation1Panel_2);
            app.AddSign2.Text = '+';
            app.X1 = uieditfield(app.Equation1Panel_2, 'numeric');
            app.Equation3Panel = uipanel(app.EqTab3);
            app.Equation3Panel.Title = 'Equation 3';
            app.AddSignEq3_2 = uilabel(app.Equation3Panel);
            app.AddSignEq3_2.Text = '+';
            app.EqualToEq3 = uilabel(app.Equation3Panel);
            app.EqualToEq3.Text = '=';
            app.AddSignEq3 = uilabel(app.Equation3Panel);
            app.AddSignEq3.Text = '+';
            app.X3 = uieditfield(app.Equation3Panel, 'numeric');
            app.xEditField_10Label_8 = uilabel(app.Equation3Panel);
            app.xEditField_10Label_8.Text = 'x';
            app.Y3 = uieditfield(app.Equation3Panel, 'numeric');
            app.xEditField_10Label_9 = uilabel(app.Equation3Panel);
            app.xEditField_10Label_9.Text = 'y';
            app.Z3 = uieditfield(app.Equation3Panel, 'numeric');
            app.xEditField_10Label_10 = uilabel(app.Equation3Panel);
            app.xEditField_10Label_10.Text = 'z';
            app.C3 = uieditfield(app.Equation3Panel, 'numeric');
            app.Equation2Panel_2 = uipanel(app.EqTab3);
            app.Equation2Panel_2.Title = 'Equation 2';
            app.AddSignEq2 = uilabel(app.Equation2Panel_2);
            app.AddSignEq2.Text = '+';
            app.EqualToEq2 = uilabel(app.Equation2Panel_2);
            app.EqualToEq2.Text = '=';
            app.AddSignEq2_2 = uilabel(app.Equation2Panel_2);
            app.AddSignEq2_2.Text = '+';
            app.X2 = uieditfield(app.Equation2Panel_2, 'numeric');
            app.xEditField_10Label_5 = uilabel(app.Equation2Panel_2);
            app.xEditField_10Label_5.Text = 'x';
            app.Y2 = uieditfield(app.Equation2Panel_2, 'numeric');
            app.xEditField_10Label_6 = uilabel(app.Equation2Panel_2);
            app.xEditField_10Label_6.Text = 'y';
            app.Z2 = uieditfield(app.Equation2Panel_2, 'numeric');
            app.xEditField_10Label_7 = uilabel(app.Equation2Panel_2);
            app.xEditField_10Label_7.Text = 'z';
            app.C2 = uieditfield(app.Equation2Panel_2, 'numeric');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.NumOfVariables = uidropdown(app.UIFigure);
            app.NumOfVariables.Items = {'Three Variables', 'Two Variables'};
            app.NumOfVariables.Value = 'Three Variables';
            app.HowManyVariablesyourequationhaveDropDownLabel = uilabel(app.UIFigure);
            app.HowManyVariablesyourequationhaveDropDownLabel.Text = 'How Many Variables your equation have';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end